classdef POM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OptionsPanel               matlab.ui.container.Panel
        ChoosestartingpointButton  matlab.ui.control.Button
        StartsegmentationButton    matlab.ui.control.Button
        Make3DvisualizationButton  matlab.ui.control.Button
        ImagesPanel                matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        NextButton                 matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        PathPanel                  matlab.ui.container.Panel
        LoadimagesButton           matlab.ui.control.Button
        Label_2                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        myFolder % Description
        currentFileNumber
        numberOfFiles
        D                       %contains with all images
        positionX
        positionY
        mask
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimagesButton
        function LoadimagesButtonPushed(app, event)
app.myFolder = uigetdir('E:\studia\VI sem\Moje\POM\Projekt-POM\MR-głowa\STU0001');%('C:\', 'Choose directory');                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isdir(app.myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(app.myFolder, '*.dcm'); % Change to whatever pattern you need.           %case do roznych wybranych rozszerzen

theFiles = dir(filePattern);

hWaitBar = waitbar(0,'Reading DICOM files');
for i=length(theFiles):-1:1
  file = fullfile(app.myFolder, theFiles(i).name);
%   app.D(:,:,i) = im2double(dicomread(file));                                                     %wczesniej było uint16
    app.D(:,:,i) = double(dicomread(file));
    waitbar((length(theFiles)-i)/length(theFiles))
end
delete(hWaitBar)
    I1 = app.D(:,:,1);
   imshow(I1,[],'Parent', app.UIAxes);
   drawnow; % Force display to update immediately.
  
  app.numberOfFiles = length(theFiles);
  app.currentFileNumber = 1;
  app.Label_2.Text = app.myFolder;
  
  formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,length(theFiles));
  app.Label.Visible = 'on';
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %Show previous image  
            app.currentFileNumber = app.currentFileNumber-1;
            if(app.currentFileNumber == 0)          
            imshow(app.D(:,:,app.numberOfFiles),[],'Parent', app.UIAxes);            
            app.currentFileNumber = app.numberOfFiles; 
            else             
            imshow(app.D(:,:, app.currentFileNumber),[],'Parent', app.UIAxes);
            app.currentFileNumber = app.currentFileNumber-1;
            end
            
    formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,app.numberOfFiles);
  
  drawnow;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.currentFileNumber = app.currentFileNumber+1;
            if(app.currentFileNumber == app.numberOfFiles+1)
            imshow(app.D(:,:,1),[],'Parent', app.UIAxes);            
            app.currentFileNumber = 1;             
            else 
            imshow(app.D(:,:,app.currentFileNumber),[],'Parent', app.UIAxes);            
            end
            
    formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,app.numberOfFiles);
  
  drawnow;
        end

        % Button pushed function: ChoosestartingpointButton
        function ChoosestartingpointButtonPushed(app, event)
            f = figure
            imshow(app.D(:,:,app.currentFileNumber),[])
            
            [y,x]=ginput(1);
            hold on
            plot(y,x, 'xg', 'MarkerSize', 10);
            app.positionX = round(x);
            app.positionY = round(y);
            
            pause(2);
            close(f)
           
        end

        % Button pushed function: StartsegmentationButton
        function StartsegmentationButtonPushed(app, event)
            previousPixelIntensity = 0;
            se = strel('square',5); %element morfologiczny
            threshold = 20;
            for i = 1:1:app.numberOfFiles
            I = app.D(:,:, app.currentFileNumber);
            
            Isegmented = regiongrowing(I, app.positionX, app.positionY);    %segmentation
            Iclosed = imclose(Isegmented, se);                              %morfological close
            Iopen = imopen(Iclosed, se);                                    %morfological open
                                               
            
            app.mask(:,:,i) = Iopen;
            centroid = regionprops(Iclosed, 'Centroid');
            pixelIntensity = I(round(centroid.Centroid(1)), round(centroid.Centroid(2)));
                if abs(pixelIntensity -previousPixelIntensity)< threshold || i ==1      
                    previousPixelIntensity = pixelIntensity;
                    app.currentFileNumber = app.currentFileNumber +1;
                    disp(i)
                    figure
                    imshow(app.mask(:,:,i))
                    pause
                else
                    break
                end
                
            end
            

        end

        % Button pushed function: Make3DvisualizationButton
        function Make3DvisualizationButtonPushed(app, event)
         
figure;
p = patch(isosurface(double(app.mask)));
p.FaceColor = 'red';
p.EdgeColor = 'none';
daspect([1,1,1]);
camlight; 
lighting phong   
pause
close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.ChoosestartingpointButton = uibutton(app.OptionsPanel, 'push');
            app.ChoosestartingpointButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosestartingpointButtonPushed, true);
            app.ChoosestartingpointButton.Text = 'Choose starting point';
            app.StartsegmentationButton = uibutton(app.OptionsPanel, 'push');
            app.StartsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsegmentationButtonPushed, true);
            app.StartsegmentationButton.Text = 'Start segmentation';
            app.Make3DvisualizationButton = uibutton(app.OptionsPanel, 'push');
            app.Make3DvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @Make3DvisualizationButtonPushed, true);
            app.Make3DvisualizationButton.Text = 'Make 3D visualization';
            app.ImagesPanel = uipanel(app.UIFigure);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.NextButton = uibutton(app.ImagesPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ImagesPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.Label = uilabel(app.ImagesPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '1/100';
            app.PathPanel = uipanel(app.UIFigure);
            app.PathPanel.Title = 'Path';
            app.LoadimagesButton = uibutton(app.PathPanel, 'push');
            app.LoadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesButtonPushed, true);
            app.LoadimagesButton.Text = 'Load images';
            app.Label_2 = uilabel(app.PathPanel);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = POM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end